classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SamplingfreqHzEditField       matlab.ui.control.NumericEditField
        SamplingfreqHzEditFieldLabel  matlab.ui.control.Label
        ResampleButton                matlab.ui.control.Button
        PRESETSLabel                  matlab.ui.control.Label
        SIMULINKOPTIONSLabel          matlab.ui.control.Label
        AGSynthLabel                  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        savepresetButton              matlab.ui.control.Button
        loadpresetButton              matlab.ui.control.Button
        SetAsDefaultAudioFilesButton  matlab.ui.control.Button
        saveButton                    matlab.ui.control.Button
        ADVANCEDOPTIONSButton         matlab.ui.control.Button
        bandwidthEditField_3          matlab.ui.control.NumericEditField
        bandwidthEditField_3Label     matlab.ui.control.Label
        bandwidthEditField_2          matlab.ui.control.NumericEditField
        bandwidthEditField_2Label     matlab.ui.control.Label
        bandwidthEditField            matlab.ui.control.NumericEditField
        bandwidthEditFieldLabel       matlab.ui.control.Label
        loadButton                    matlab.ui.control.Button
        Filter3cutoffEditField        matlab.ui.control.NumericEditField
        Filter3cutoffEditFieldLabel   matlab.ui.control.Label
        filter3typeDropDown           matlab.ui.control.DropDown
        filter3typeDropDownLabel      matlab.ui.control.Label
        Filter2cutoffEditField        matlab.ui.control.NumericEditField
        Filter2cutoffEditFieldLabel   matlab.ui.control.Label
        filter2typeDropDown           matlab.ui.control.DropDown
        filter2typeDropDownLabel      matlab.ui.control.Label
        Filter1cutoffEditField        matlab.ui.control.NumericEditField
        Filter1cutoffEditFieldLabel   matlab.ui.control.Label
        filter1typeDropDown           matlab.ui.control.DropDown
        filter1typeDropDownLabel      matlab.ui.control.Label
        MasterVolumeKnob              matlab.ui.control.Knob
        MasterVolumeKnobLabel         matlab.ui.control.Label
        pitchrangeKnob                matlab.ui.control.DiscreteKnob
        pitchrangeKnobLabel           matlab.ui.control.Label
        detune3Knob                   matlab.ui.control.DiscreteKnob
        detune3Label                  matlab.ui.control.Label
        detune2Knob                   matlab.ui.control.DiscreteKnob
        detune2Label                  matlab.ui.control.Label
        detune1Knob                   matlab.ui.control.DiscreteKnob
        detune1Label                  matlab.ui.control.Label
        interval3Knob                 matlab.ui.control.DiscreteKnob
        interval3Label                matlab.ui.control.Label
        interval2Knob                 matlab.ui.control.DiscreteKnob
        interval2Label                matlab.ui.control.Label
        interval1Knob                 matlab.ui.control.DiscreteKnob
        interval1Label                matlab.ui.control.Label
        basepitchKnob                 matlab.ui.control.Knob
        basepitchKnobLabel            matlab.ui.control.Label
        Audio3EditField               matlab.ui.control.EditField
        Audio3EditFieldLabel          matlab.ui.control.Label
        ImportButton_3                matlab.ui.control.Button
        Audio2EditField               matlab.ui.control.EditField
        Audio2EditFieldLabel          matlab.ui.control.Label
        ImportButton_2                matlab.ui.control.Button
        Audio1EditField               matlab.ui.control.EditField
        Audio1EditFieldLabel          matlab.ui.control.Label
        ImportButton                  matlab.ui.control.Button
        Voice3Knob                    matlab.ui.control.Knob
        Voice3KnobLabel               matlab.ui.control.Label
        Voice2Knob                    matlab.ui.control.Knob
        Voice2KnobLabel               matlab.ui.control.Label
        Voice1Knob                    matlab.ui.control.Knob
        Voice1KnobLabel               matlab.ui.control.Label
        STARTButton                   matlab.ui.control.Button
        STOPButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        mdl = 'merge_variable_grain'; % Description
        app2;
        running = 0;   
    end
    
    properties (Access = public)
        state = struct(); % keeps track of all tuning varaibles
    end
    
    methods (Access = public)
        
        
    end
    
    methods (Access = private)
        
        function updateUIfromstate(app)

            app.Audio1EditField.Value = app.state.filepath1;
            app.Audio2EditField.Value = app.state.filepath2;
            app.Audio3EditField.Value = app.state.filepath3;

            app.filter1typeDropDown.Value = app.state.filter1type;
            app.filter2typeDropDown.Value = app.state.filter2type;
            app.filter3typeDropDown.Value = app.state.filter3type;

            if app.state.filter1type == "Lowpass"
                app.Filter1cutoffEditField.Value = app.state.filter1cutofffreq;
            elseif app.state.filter1type == "Bandpass"
                app.Filter1cutoffEditField.Value = app.state.filter1centerfreq;
            elseif app.state.filter1type == "Highpass"
                app.Filter1cutoffEditField.Value = app.state.filter1cutofffreq;
            end

            if app.state.filter2type == "Lowpass"
                app.Filter2cutoffEditField.Value = app.state.filter2cutofffreq;
            elseif app.state.filter2type == "Bandpass"
                app.Filter2cutoffEditField.Value = app.state.filter2centerfreq;
            elseif app.state.filter2type == "Highpass"
                app.Filter2cutoffEditField.Value = app.state.filter2cutofffreq;
            end

            if app.state.filter1type == "Lowpass"
                app.Filter3cutoffEditField.Value = app.state.filter3cutofffreq;
            elseif app.state.filter1type == "Bandpass"
                app.Filter3cutoffEditField.Value = app.state.filter3centerfreq;
            elseif app.state.filter1type == "Highpass"
                app.Filter3cutoffEditField.Value = app.state.filter3cutofffreq;
            end

            app.bandwidthEditField.Value = app.state.filter1bandwidth;
            app.bandwidthEditField_2.Value = app.state.filter2bandwidth; 
            app.bandwidthEditField_3.Value = app.state.filter3bandwidth;

            app.MasterVolumeKnob.Value = app.state.mastervolume;
            app.Voice1Knob.Value = app.state.voice1;
            app.Voice2Knob.Value = app.state.voice2;
            app.Voice3Knob.Value = app.state.voice3;

            app.basepitchKnob.Value = app.state.basepitch;
            app.pitchrangeKnob.Value = app.state.pitchrange;

            app.interval1Knob.Value = app.state.interval1;
            app.interval2Knob.Value = app.state.interval2;
            app.interval3Knob.Value = app.state.interval3;

            app.detune1Knob.Value = app.state.detune1;
            app.detune2Knob.Value = app.state.detune2;
            app.detune3Knob.Value = app.state.detune3;

            if app.state.filepath1 ~= "" && app.state.filepath2 ~= "" && app.state.filepath3 ~= ""
                app.SetAsDefaultAudioFilesButton.Enable = "on";
            end

            app.SamplingfreqHzEditField.Value = app.state.sam_freq;

        end
        
        
        function updateSimulinkFromState(app)
            
            if app.state.filter1type == "Lowpass"
                set_param([app.mdl, '/Filter1/switch' ], 'Value', '1')
                app.bandwidthEditField.Enable = 0;
                app.bandwidthEditFieldLabel.Enable = 0;
            elseif app.state.filter1type == "Bandpass"
                set_param([app.mdl, '/Filter1/switch' ], 'Value', '0')
                app.bandwidthEditField.Enable = 1;
                app.bandwidthEditFieldLabel.Enable = 1;
            elseif app.state.filter1type == "Highpass"
                set_param([app.mdl, '/Filter1/switch' ], 'Value', '2')
                app.bandwidthEditField.Enable = 0;
                app.bandwidthEditFieldLabel.Enable = 0;
            end

            if app.state.filter2type == "Lowpass"
                set_param([app.mdl, '/Filter2/switch' ], 'Value', '1')
                app.bandwidthEditField_2.Enable = 0;
                app.bandwidthEditField_2Label.Enable = 0;
            elseif app.state.filter2type == "Bandpass"
                set_param([app.mdl, '/Filter2/switch' ], 'Value', '0')
                app.bandwidthEditField_2.Enable = 1;
                app.bandwidthEditField_2Label.Enable = 1;
            elseif app.state.filter2type == "Highpass"
                set_param([app.mdl, '/Filter2/switch' ], 'Value', '2')
                app.bandwidthEditField_2.Enable = 0;
                app.bandwidthEditField_2Label.Enable = 0;
            end

            if app.state.filter3type == "Lowpass"
                set_param([app.mdl, '/Filter3/switch' ], 'Value', '1')
                app.bandwidthEditField_3.Enable = 0;
                app.bandwidthEditField_3Label.Enable = 0;
            elseif app.state.filter3type == "Bandpass"
                set_param([app.mdl, '/Filter3/switch' ], 'Value', '0')
                app.bandwidthEditField_3.Enable = 1;
                app.bandwidthEditField_3Label.Enable = 1;
            elseif app.state.filter3type == "Highpass"
                set_param([app.mdl, '/Filter3/switch' ], 'Value', '2')
                app.bandwidthEditField_3.Enable = 0;
                app.bandwidthEditField_3Label.Enable = 0;
            end
            

            set_param([app.mdl, '/Filter1/cutoff_freq' ], 'Value', string(app.state.filter1cutofffreq))
            set_param([app.mdl, '/Filter1/center_freq' ], 'Value', string(app.state.filter1centerfreq))
            set_param([app.mdl, '/Filter1/bandwidth' ], 'Value', string(app.state.filter1bandwidth))

            set_param([app.mdl, '/Filter2/cutoff_freq' ], 'Value', string(app.state.filter2cutofffreq))
            set_param([app.mdl, '/Filter2/center_freq' ], 'Value', string(app.state.filter2centerfreq))
            set_param([app.mdl, '/Filter2/bandwidth' ], 'Value', string(app.state.filter2bandwidth))

            set_param([app.mdl, '/Filter3/cutoff_freq' ], 'Value', string(app.state.filter3cutofffreq))
            set_param([app.mdl, '/Filter3/center_freq' ], 'Value', string(app.state.filter3centerfreq))
            set_param([app.mdl, '/Filter3/bandwidth' ], 'Value', string(app.state.filter3bandwidth))

            set_param([app.mdl, '/master volume' ], 'Gain', string(app.state.mastervolume))
            set_param([app.mdl, '/VOLUME 1'], 'Gain', string(app.state.voice1))
            set_param([app.mdl, '/VOLUME 2'], 'Gain', string(app.state.voice2))
            set_param([app.mdl, '/VOLUME 3'], 'Gain', string(app.state.voice3))

            set_param([app.mdl, '/Biquad Filtering/base pitch' ], 'Value', string(app.state.basepitch))
            set_param([app.mdl, '/Biquad Filtering/pitch range' ], 'Value', string(app.state.pitchrange))

            set_param([app.mdl, '/voice_settings/interval1' ], 'Value', string(app.state.interval1))
            set_param([app.mdl, '/voice_settings/interval2' ], 'Value', string(app.state.interval2))
            set_param([app.mdl, '/voice_settings/interval3' ], 'Value', string(app.state.interval3))

            set_param([app.mdl, '/voice_settings/detune1' ], 'Value', string(app.state.detune1))
            set_param([app.mdl, '/voice_settings/detune2' ], 'Value', string(app.state.detune2))
            set_param([app.mdl, '/voice_settings/detune3' ], 'Value', string(app.state.detune3))

            set_param([app.mdl, '/min_grain_size' ], 'Value', string(app.state.mingrainsize))
            set_param([app.mdl, '/max_grain_size' ], 'Value', string(app.state.maxgrainsize))
            set_param([app.mdl, '/variation on average length' ], 'Value', string(app.state.max_variance))
            set_param([app.mdl, '/voice_settings/exp1' ], 'Value', app.state.pitch_dyn_1)
            set_param([app.mdl, '/voice_settings/exp2' ], 'Value', app.state.pitch_dyn_2)
            set_param([app.mdl, '/voice_settings/exp3' ], 'Value', app.state.pitch_dyn_3)
            set_param([app.mdl, '/Cosine Fraction1' ], 'Value', string(app.state.cosfraction1))
            set_param([app.mdl, '/Cosine Fraction2' ], 'Value', string(app.state.cosfraction2))
            set_param([app.mdl, '/Cosine Fraction3' ], 'Value', string(app.state.cosfraction3))

        end
        
        function ret = check_audio_imports(app)
            ret = 0;
            if app.state.filepath1 ~= "" && app.state.filepath2 ~= "" && app.state.filepath3 ~= ""
                ret = 1;
            else
                ret = 0;
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.ADVANCEDOPTIONSButton.Enable = "off";
            app.STARTButton.Enable = 'off';
            app.STOPButton.Enable = 'off';
            app.SetAsDefaultAudioFilesButton.Enable = "off";
            app.state.filepath1 = "";
            app.state.filepath2 = "";
            app.state.filepath3 = "";
            app.Label.Text = "PRESS LOAD OR LOAD PRESET";
            
            value = app.SamplingfreqHzEditField.Value;
            assignin('base', 'f_resample', value)
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)

            app.saveButton.Enable = 'off';
            app.loadButton.Enable = 'off';
            app.ImportButton.Enable ='off';
            app.ImportButton_2.Enable = 'off';
            app.ImportButton_3.Enable ='off';
            app.loadpresetButton.Enable = 'off';
            app.running = 1;
            app.STOPButton.Enable = 'on';
            app.STARTButton.Enable = 'off';
            app.Label.Text = "WAIT FOR COMPILATION";
            drawnow;
            simout=sim(app.mdl);
            assignin("base","simout",simout)
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            simulink.compiler.stopSimulation(app.mdl);
            app.running = 0;
            app.saveButton.Enable = 'on';
            app.loadButton.Enable = 'on';
            app.ImportButton.Enable ='on';
            app.ImportButton_2.Enable = 'on';
            app.ImportButton_3.Enable ='on';
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            app.loadpresetButton.Enable = 'on';
            app.Label.Text = "STOPPED";
        end

        % Value changed function: Voice1Knob
        function Voice1KnobValueChanged(app, event)
            value = app.Voice1Knob.Value;
            set_param([app.mdl, '/VOLUME 1'], 'Gain', string(value))
            app.state.voice1 = value;
        end

        % Value changed function: Voice2Knob
        function Voice2KnobValueChanged(app, event)
            value = app.Voice2Knob.Value;
            set_param([app.mdl, '/VOLUME 2'], 'Gain', string(value))
            app.state.voice2 = value;
        end

        % Value changed function: Voice3Knob
        function Voice3KnobValueChanged(app, event)
            value = app.Voice3Knob.Value;
            set_param([app.mdl, '/VOLUME 3'], 'Gain', string(value))
            app.state.voice3 = value;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [filename,path] = uigetfile('*.wav');
            if filename == 0
                return;
            end

            app.Audio1EditField.Value = strcat("./Audio/", filename);
            app.state.filepath1 = strcat("./Audio/", filename);
            %app.state.filepath1 = fullfile(path, filename);
            
            [in,fs] = audioread([path, filename]);
            assignin('base','audio_1',in);
            assignin('base','fs',fs);
            assignin('base', 'max_s1', length(in))

            evalin('base',"resample_workspace")

            if app.state.filepath1 ~= "" && app.state.filepath2 ~= "" && app.state.filepath3 ~= ""
                app.SetAsDefaultAudioFilesButton.Enable = "on";
            end
        end

        % Button pushed function: ImportButton_2
        function ImportButton_2Pushed(app, event)
            [filename,path] = uigetfile('*.wav');
            if filename == 0
                return;
            end

            app.Audio2EditField.Value = strcat("./Audio/", filename);
            app.state.filepath2 = strcat("./Audio/", filename);

            [in,fs] = audioread([path, filename]);
            assignin('base','audio_2',in);
            assignin('base','fs2',fs);
            assignin('base', 'max_s2', length(in))

            evalin('base',"resample_workspace")

            if app.state.filepath1 ~= "" && app.state.filepath2 ~= "" && app.state.filepath3 ~= ""
                app.SetAsDefaultAudioFilesButton.Enable = "on";
            end
        end

        % Button pushed function: ImportButton_3
        function ImportButton_3Pushed(app, event)
            [filename,path] = uigetfile('*.wav');
            if filename == 0
                return;
            end
            
            app.Audio3EditField.Value = strcat("./Audio/", filename);
            app.state.filepath3 = strcat("./Audio/", filename);
            
            [in,fs] = audioread([path, filename]);
            assignin('base','audio_3',in);
            assignin('base','fs3',fs);
            assignin('base', 'max_s3', length(in))

            evalin('base',"resample_workspace")

            if app.state.filepath1 ~= "" && app.state.filepath2 ~= "" && app.state.filepath3 ~= ""
                app.SetAsDefaultAudioFilesButton.Enable = "on";
            end
        end

        % Callback function
        function SPEEDSliderValueChanged(app, event)
            value = app.SPEEDSlider.Value;
            set_param([app.mdl, '/Biquad Filtering/Dashboard ECU logic1/Commanded speed/Speed command' ], ...
                'Value', string(value))
        end

        % Value changed function: basepitchKnob
        function basepitchKnobValueChanged(app, event)
            value = app.basepitchKnob.Value;
            set_param([app.mdl, '/Biquad Filtering/base pitch' ], 'Value', string(value))
            app.state.basepitch = value;
        end

        % Value changed function: interval1Knob
        function interval1KnobValueChanged(app, event)
            value = app.interval1Knob.Value;
            set_param([app.mdl, '/voice_settings/interval1' ], 'Value', string(value))
            app.state.interval1 = string(value);
        end

        % Value changed function: interval2Knob
        function interval2KnobValueChanged(app, event)
            value = app.interval2Knob.Value;
            set_param([app.mdl, '/voice_settings/interval2' ], 'Value', string(value))
            app.state.interval2 = string(value);
        end

        % Value changed function: interval3Knob
        function interval3KnobValueChanged(app, event)
            value = app.interval3Knob.Value;
            set_param([app.mdl, '/voice_settings/interval3' ], 'Value', string(value))
            app.state.interval3 = string(value);
        end

        % Value changed function: detune1Knob
        function detune1KnobValueChanged(app, event)
            value = app.detune1Knob.Value;
            set_param([app.mdl, '/voice_settings/detune1' ], 'Value', string(value))
            app.state.detune1 = string(value);
        end

        % Value changed function: detune2Knob
        function detune2KnobValueChanged(app, event)
            value = app.detune2Knob.Value;
            set_param([app.mdl, '/voice_settings/detune2' ], 'Value', string(value))
            app.state.detune2 = string(value);
        end

        % Value changed function: detune3Knob
        function detune3KnobValueChanged(app, event)
            value = app.detune3Knob.Value;
            set_param([app.mdl, '/voice_settings/detune3' ], 'Value', string(value))
            app.state.detune3 = string(value);
        end

        % Value changed function: pitchrangeKnob
        function pitchrangeKnobValueChanged(app, event)
            value = app.pitchrangeKnob.Value;
            set_param([app.mdl, '/Biquad Filtering/pitch range' ], 'Value', string(value))
            app.state.pitchrange = string(value);
        end

        % Value changed function: MasterVolumeKnob
        function MasterVolumeKnobValueChanged(app, event)
            value = app.MasterVolumeKnob.Value;
            set_param([app.mdl, '/master volume' ], 'Gain', string(value))
            app.state.mastervolume = value;
        end

        % Drop down opening function: filter1typeDropDown
        function filter1typeDropDownOpening(app, event)
            
        end

        % Value changed function: filter1typeDropDown
        function filter1typeDropDownValueChanged(app, event)
            value = app.filter1typeDropDown.Value;

            app.state.filter1type = string(value);
            
            if string(value) == "Lowpass"
                f = get_param([app.mdl, '/Filter1/cutoff_freq' ], 'Value');
                app.Filter1cutoffEditField.Value = str2double(f);
                app.bandwidthEditField.Enable = 0;
                app.bandwidthEditFieldLabel.Enable = 0;
                app.Filter1cutoffEditFieldLabel.Text = 'Cutoff Freq';
                set_param([app.mdl, '/Filter1/switch' ], 'Value', '1')
            end
            if string(value) == "Highpass"
                f = get_param([app.mdl, '/Filter1/cutoff_freq' ], 'Value');
                app.Filter1cutoffEditField.Value = str2double(f);
                app.bandwidthEditField.Enable = 0;
                app.bandwidthEditFieldLabel.Enable = 0;
                app.Filter1cutoffEditFieldLabel.Text = 'Cutoff Freq';
                set_param([app.mdl, '/Filter1/switch' ], 'Value', '2')
            end
            if string(value) == "Bandpass"
                f = get_param([app.mdl, '/Filter1/center_freq' ], 'Value');
                app.Filter1cutoffEditField.Value = str2double(f);
                app.bandwidthEditField.Enable = 1;
                app.bandwidthEditFieldLabel.Enable = 1;
                app.Filter1cutoffEditFieldLabel.Text = 'Center Freq';
                set_param([app.mdl, '/Filter1/switch' ], 'Value', '0')
            end

        end

        % Value changed function: Filter1cutoffEditField
        function Filter1cutoffEditFieldValueChanged(app, event)
            value = app.Filter1cutoffEditField.Value;
            type = app.filter1typeDropDown.Value;
            if string(type) == "Lowpass"
                set_param([app.mdl, '/Filter1/cutoff_freq' ], 'Value', string(value))
                app.state.filter1cutofffreq = value;
            end
            if string(type) == "Highpass"
                set_param([app.mdl, '/Filter1/cutoff_freq' ], 'Value', string(value))
                app.state.filter1cutofffreq = value;
            end
            if string(type) == "Bandpass"
                set_param([app.mdl, '/Filter1/center_freq' ], 'Value', string(value))
                app.state.filter1centerfreq = value;
            end
        end

        % Value changed function: filter2typeDropDown
        function filter2typeDropDownValueChanged(app, event)
            value = app.filter2typeDropDown.Value;

            app.state.filter2type = string(value);
            
            if string(value) == "Lowpass"
                f = get_param([app.mdl, '/Filter2/cutoff_freq' ], 'Value');
                app.Filter2cutoffEditField.Value = str2double(f);
                app.bandwidthEditField_2.Enable = 0;
                app.bandwidthEditField_2Label.Enable = 0;
                app.Filter2cutoffEditFieldLabel.Text = 'Cutoff Freq';
                set_param([app.mdl, '/Filter2/switch' ], 'Value', '1')
            end
            if string(value) == "Highpass"
                f = get_param([app.mdl, '/Filter2/cutoff_freq' ], 'Value');
                app.Filter2cutoffEditField.Value = str2double(f);
                app.bandwidthEditField_2.Enable = 0;
                app.bandwidthEditField_2Label.Enable = 0;
                app.Filter2cutoffEditFieldLabel.Text = 'Cutoff Freq';
                set_param([app.mdl, '/Filter2/switch' ], 'Value', '2')
            end
            if string(value) == "Bandpass"
                f = get_param([app.mdl, '/Filter2/center_freq' ], 'Value');
                app.Filter2cutoffEditField.Value = str2double(f);
                app.bandwidthEditField_2.Enable = 1;
                app.bandwidthEditField_2Label.Enable = 1;
                app.Filter2cutoffEditFieldLabel.Text = 'Center Freq';
                set_param([app.mdl, '/Filter2/switch' ], 'Value', '0')
            end
        end

        % Value changed function: Filter2cutoffEditField
        function Filter2cutoffEditFieldValueChanged(app, event)
            value = app.Filter2cutoffEditField.Value;
            type = app.filter2typeDropDown.Value;
            if string(type) == "Lowpass"
                set_param([app.mdl, '/Filter2/cutoff_freq' ], 'Value', string(value))
                app.state.filter2cutofffreq = value;
            end
            if string(type) == "Highpass"
                set_param([app.mdl, '/Filter2/cutoff_freq' ], 'Value', string(value))
                app.state.filter2cutofffreq = value;
            end
            if string(type) == "Bandpass"
                set_param([app.mdl, '/Filter2/center_freq' ], 'Value', string(value))
                app.state.filter2centerfreq = value;
            end
        end

        % Value changed function: Filter3cutoffEditField
        function Filter3cutoffEditFieldValueChanged(app, event)
            value = app.Filter3cutoffEditField.Value;
            type = app.filter3typeDropDown.Value;
            if string(type) == "Lowpass"
                set_param([app.mdl, '/Filter3/cutoff_freq' ], 'Value', string(value))
                app.state.filter3cutofffreq = value;
            end
            if string(type) == "Highpass"
                set_param([app.mdl, '/Filter3/cutoff_freq' ], 'Value', string(value))
                app.state.filter3cutofffreq = value;
            end
            if string(type) == "Bandpass"
                set_param([app.mdl, '/Filter3/center_freq' ], 'Value', string(value))
                app.state.filter3centerfreq = value;
            end
        end

        % Value changed function: filter3typeDropDown
        function filter3typeDropDownValueChanged(app, event)
            value = app.filter3typeDropDown.Value;

            app.state.filter3type = string(value);
            
            if string(value) == "Lowpass"
                f = get_param([app.mdl, '/Filter3/cutoff_freq' ], 'Value');
                app.Filter3cutoffEditField.Value = str2double(f);
                app.bandwidthEditField_3.Enable = 0;
                app.bandwidthEditField_3Label.Enable = 0;
                app.Filter3cutoffEditFieldLabel.Text = 'Cutoff Freq';
                set_param([app.mdl, '/Filter3/switch' ], 'Value', '1')
            end
            if string(value) == "Highpass"
                f = get_param([app.mdl, '/Filter3/cutoff_freq' ], 'Value');
                app.Filter3cutoffEditField.Value = str2double(f);
                app.bandwidthEditField_3.Enable = 0;
                app.bandwidthEditField_3Label.Enable = 0;
                app.Filter3cutoffEditFieldLabel.Text = 'Cutoff Freq';
                set_param([app.mdl, '/Filter3/switch' ], 'Value', '2')
            end
            if string(value) == "Bandpass"
                f = get_param([app.mdl, '/Filter3/center_freq' ], 'Value');
                app.Filter3cutoffEditField.Value = str2double(f);
                app.bandwidthEditField_3.Enable = 1;
                app.bandwidthEditField_3Label.Enable = 1;
                app.Filter3cutoffEditFieldLabel.Text = 'Center Freq';
                set_param([app.mdl, '/Filter3/switch' ], 'Value', '0')
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.running == 1
                simulink.compiler.stopSimulation(app.mdl);
            end
            if app.ADVANCEDOPTIONSButton.Enable == 0
                delete(app.app2)
            end
            delete(app)
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            try
                evalin('base',"inizio")
            catch ME
                app.Label.Text = "CHECK FOR ERRORS";
                rethrow(ME)
            end
    
            app.Label.Text = "WAIT";
            drawnow


            load_system(app.mdl);

            app.ADVANCEDOPTIONSButton.Enable = "on";

            app.state.filepath1 = evalin('base', 'selected_audio.filepath1');
            app.state.filepath2 = evalin('base', 'selected_audio.filepath2');
            app.state.filepath3 = evalin('base', 'selected_audio.filepath3');
            

            c = str2double(get_param([app.mdl, '/Filter1/switch' ], 'Value'));
            if c == 1
                app.state.filter1type = 'Lowpass';                
                app.bandwidthEditField.Enable = 0;
                app.bandwidthEditFieldLabel.Enable = 0;
                app.state.filter1bandwidth = 0;
            elseif c == 0
                app.state.filter1type = 'Bandpass';
                app.bandwidthEditField.Enable = 1;
                app.bandwidthEditFieldLabel.Enable = 1;
            elseif c == 2
                app.state.filter1type = 'Highpass';                
                app.bandwidthEditField.Enable = 0;
                app.bandwidthEditFieldLabel.Enable = 0;
                app.state.filter1bandwidth = 0;
            end

            f = get_param([app.mdl, '/Filter1/cutoff_freq' ], 'Value');
                app.state.filter1cutofffreq = str2double(f);
            f = get_param([app.mdl, '/Filter1/center_freq' ], 'Value');
                app.state.filter1centerfreq = str2double(f);
            f = get_param([app.mdl, '/Filter1/bandwidth' ], 'Value');
                app.state.filter1bandwidth = str2double(f);

            c = str2double(get_param([app.mdl, '/Filter2/switch' ], 'Value'));
            if c == 1
                app.state.filter2type = 'Lowpass';
                app.bandwidthEditField_2.Enable = 0;
                app.bandwidthEditField_2Label.Enable = 0;
                app.state.filter2bandwidth = 0;
            elseif c == 0
                app.state.filter2type = 'Bandpass';
                app.bandwidthEditField_2.Enable = 1;
                app.bandwidthEditField_2Label.Enable = 1;
            elseif c == 2
                app.state.filter2type = 'Highpass';
                app.bandwidthEditField_2.Enable = 0;
                app.bandwidthEditField_2Label.Enable = 0;
                app.state.filter2bandwidth = 0;
            end

            f = get_param([app.mdl, '/Filter2/cutoff_freq' ], 'Value');
                app.state.filter2cutofffreq = str2double(f);
            f = get_param([app.mdl, '/Filter2/center_freq' ], 'Value');
                app.state.filter2centerfreq = str2double(f);
            f = get_param([app.mdl, '/Filter2/bandwidth' ], 'Value');
                app.state.filter2bandwidth = str2double(f);

            c = str2double(get_param([app.mdl, '/Filter3/switch' ], 'Value'));
            if c == 1
                app.state.filter3type = 'Lowpass';
                app.bandwidthEditField_3.Enable = 0;
                app.bandwidthEditField_3Label.Enable = 0;
                app.state.filter3bandwidth = 0;
            elseif c == 0
                app.state.filter3type = 'Bandpass';
                app.bandwidthEditField_3.Enable = 1;
                app.bandwidthEditField_3Label.Enable = 1;
            elseif c == 2
                app.state.filter3type = 'Highpass';
                app.bandwidthEditField_3.Enable = 0;
                app.bandwidthEditField_3Label.Enable = 0;
                app.state.filter3bandwidth = 0;
            end

            f = get_param([app.mdl, '/Filter3/cutoff_freq' ], 'Value');
                app.state.filter3cutofffreq = str2double(f);
            f = get_param([app.mdl, '/Filter3/center_freq' ], 'Value');
                app.state.filter3centerfreq = str2double(f);
            f = get_param([app.mdl, '/Filter3/bandwidth' ], 'Value');
                app.state.filter3bandwidth = str2double(f);
            
            app.state.mastervolume = str2double(get_param([app.mdl, '/master volume' ], 'Gain'));
            app.state.voice1 = str2double(get_param([app.mdl, '/VOLUME 1' ], 'Gain'));
            app.state.voice2 = str2double(get_param([app.mdl, '/VOLUME 2' ], 'Gain'));
            app.state.voice3 = str2double(get_param([app.mdl, '/VOLUME 3' ], 'Gain'));

            app.state.basepitch = str2double(get_param([app.mdl, '/Biquad Filtering/base pitch' ], 'Value'));
            app.state.pitchrange = string(floor(str2double(get_param([app.mdl, '/Biquad Filtering/pitch range' ], 'Value'))));

            app.state.detune1 = string(floor(str2double(get_param([app.mdl, '/voice_settings/detune1' ], 'Value'))));
            app.state.detune2 = string(floor(str2double(get_param([app.mdl, '/voice_settings/detune2' ], 'Value'))));
            app.state.detune3 = string(floor(str2double(get_param([app.mdl, '/voice_settings/detune3' ], 'Value'))));

            app.state.interval1 = string(floor(str2double(get_param([app.mdl, '/voice_settings/interval1' ], 'Value'))));
            app.state.interval2 = string(floor(str2double(get_param([app.mdl, '/voice_settings/interval2' ], 'Value'))));
            app.state.interval3 = string(floor(str2double(get_param([app.mdl, '/voice_settings/interval3' ], 'Value'))));

            app.state.mingrainsize = get_param([app.mdl, '/min_grain_size' ], 'Value');        
            app.state.maxgrainsize = get_param([app.mdl, '/max_grain_size' ], 'Value');          
            app.state.max_variance = get_param([app.mdl, '/variation on average length' ], 'Value');       
            app.state.pitch_dyn_1 = get_param([app.mdl, '/voice_settings/exp1' ], 'Value');           
            app.state.pitch_dyn_2 = get_param([app.mdl, '/voice_settings/exp2' ], 'Value');
            app.state.pitch_dyn_3 = get_param([app.mdl, '/voice_settings/exp3' ], 'Value');
            app.state.cosfraction1 =  get_param([app.mdl, '/Cosine Fraction1' ], 'Value');
            app.state.cosfraction2 =  get_param([app.mdl, '/Cosine Fraction2' ], 'Value');
            app.state.cosfraction3 =  get_param([app.mdl, '/Cosine Fraction3' ], 'Value');

            app.state.sam_freq = app.SamplingfreqHzEditField.Value;
            assignin("base", "f_resample", app.state.sam_freq)

            evalin('base',"resample_workspace")
            
            assignin('base', "curr_state", app.state)
            app.updateUIfromstate();
            app.STARTButton.Enable = 'on';
            app.Label.Text = "PRESS START TO BEGIN";

        end

        % Value changed function: bandwidthEditField
        function bandwidthEditFieldValueChanged(app, event)
            value = app.bandwidthEditField.Value;
            c_freq = app.Filter1cutoffEditField.Value;
            if (c_freq - value/2) <= 0
                set_param([app.mdl, '/Filter1/bandwidth' ], 'Value', string(c_freq*2 - 1))
                app.bandwidthEditField.Value = c_freq*2 - 1;
                app.state.filter1bandwidth = c_freq*2 - 1;
            else
                set_param([app.mdl, '/Filter1/bandwidth' ], 'Value', string(value))
                app.state.filter1bandwidth = value;
            end
        end

        % Value changed function: bandwidthEditField_2
        function bandwidthEditField_2ValueChanged(app, event)
            value = app.bandwidthEditField_2.Value;
            c_freq = app.Filter2cutoffEditField.Value;
            if (c_freq - value/2) <= 0
                set_param([app.mdl, '/Filter2/bandwidth' ], 'Value', string(c_freq*2 - 1))
                app.bandwidthEditField_2.Value = c_freq*2 - 1;
                app.state.filter2bandwidth = c_freq*2 - 1;
            else
                set_param([app.mdl, '/Filter2/bandwidth' ], 'Value', string(value))
                app.state.filter2bandwidth = value;
            end
        end

        % Value changed function: bandwidthEditField_3
        function bandwidthEditField_3ValueChanged(app, event)
            value = app.bandwidthEditField_3.Value;
            c_freq = app.Filter3cutoffEditField.Value;
            if (c_freq - value/2) <= 0
                set_param([app.mdl, '/Filter3/bandwidth' ], 'Value', string(c_freq*2 - 1))
                app.bandwidthEditField_3.Value = c_freq*2 - 1;
                app.state.filter3bandwidth = c_freq*2 - 1;
            else
                set_param([app.mdl, '/Filter3/bandwidth' ], 'Value', string(value))
                app.state.filter3bandwidth = value;
            end
        end

        % Button pushed function: ADVANCEDOPTIONSButton
        function ADVANCEDOPTIONSButtonPushed(app, event)
            
            app.ADVANCEDOPTIONSButton.Enable = 'off';
            app.app2 = app2(app);
            
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            save_system(app.mdl)
        end

        % Value changed function: Audio1EditField
        function Audio1EditFieldValueChanged(app, event)
            value = app.Audio1EditField.Value;
        end

        % Button pushed function: SetAsDefaultAudioFilesButton
        function SetAsDefaultAudioFilesButtonPushed(app, event)
            selected_audio = struct();
            selected_audio.filepath1 = app.state.filepath1;
            selected_audio.filepath2 = app.state.filepath2;
            selected_audio.filepath3 = app.state.filepath3;
            assignin('base', 'selected_audio', selected_audio);
            save('default_audio.mat', 'selected_audio');
        end

        % Button pushed function: savepresetButton
        function savepresetButtonPushed(app, event)
            assignin('base', "state", app.state)
            [filename, pathname] = uiputfile('*.mat','Save Workspace Variables As');
            newfilename = fullfile(pathname, filename);
            state = app.state;
            save(newfilename, 'state')
        end

        % Button pushed function: loadpresetButton
        function loadpresetButtonPushed(app, event)
            [filename,pathname] = uigetfile('/Presets/*.mat');
            newfilename = fullfile(pathname, filename);
            try
                a = load(newfilename, "state");
            catch ME
                app.Label.Text = "UNABLE TO LOAD PRESET";
                rethrow(ME)
            end
            
            assignin('base', "preset_state", a.state)
            app.state = a.state;
            try
                evalin('base', 'load_preset_audio');
            catch ME
                app.Label.Text = "UNABLE TO LOAD PRESET AUDIO";
                rethrow(ME)
            end
            
            
            load_system(app.mdl)
            app.ADVANCEDOPTIONSButton.Enable = "on";
    
            assignin("base", "f_resample", app.state.sam_freq)
            evalin('base',"resample_workspace")
            
            try
                app.updateSimulinkFromState();
            catch ME
                app.Label.Text = "UNABLE TO LOAD STATE";
                rethrow(ME)
            end
            try
                app.updateUIfromstate();
            catch ME
                app.Label.Text = "UNABLE TO UPDATE UI";
                rethrow(ME)
            end
            
            app.STARTButton.Enable = 'on';
            app.Label.Text = "PRESS START TO BEGIN";

        end

        % Button pushed function: ResampleButton
        function ResampleButtonPushed(app, event)
            f_r = app.SamplingfreqHzEditField.Value;

            app.state.sam_freq = f_r;
            assignin("base", "f_resample", f_r)
            evalin('base',"resample_workspace")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Voice1KnobLabel = uilabel(app.UIFigure);
            app.Voice1KnobLabel.Text = 'Voice 1';
            app.Voice1Knob = uiknob(app.UIFigure, 'continuous');
            app.Voice1Knob.Limits = [0 1];
            app.Voice1Knob.ValueChangedFcn = createCallbackFcn(app, @Voice1KnobValueChanged, true);
            app.Voice1Knob.Value = 0.3;
            app.Voice2KnobLabel = uilabel(app.UIFigure);
            app.Voice2KnobLabel.Text = 'Voice 2';
            app.Voice2Knob = uiknob(app.UIFigure, 'continuous');
            app.Voice2Knob.Limits = [0 1];
            app.Voice2Knob.ValueChangedFcn = createCallbackFcn(app, @Voice2KnobValueChanged, true);
            app.Voice2Knob.Value = 0.3;
            app.Voice3KnobLabel = uilabel(app.UIFigure);
            app.Voice3KnobLabel.Text = 'Voice 3';
            app.Voice3Knob = uiknob(app.UIFigure, 'continuous');
            app.Voice3Knob.Limits = [0 1];
            app.Voice3Knob.ValueChangedFcn = createCallbackFcn(app, @Voice3KnobValueChanged, true);
            app.Voice3Knob.Value = 0.3;
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.Audio1EditFieldLabel = uilabel(app.UIFigure);
            app.Audio1EditFieldLabel.Text = 'Audio 1';
            app.Audio1EditField = uieditfield(app.UIFigure, 'text');
            app.Audio1EditField.ValueChangedFcn = createCallbackFcn(app, @Audio1EditFieldValueChanged, true);
            app.ImportButton_2 = uibutton(app.UIFigure, 'push');
            app.ImportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_2Pushed, true);
            app.ImportButton_2.Text = 'Import';
            app.Audio2EditFieldLabel = uilabel(app.UIFigure);
            app.Audio2EditFieldLabel.Text = 'Audio 2';
            app.Audio2EditField = uieditfield(app.UIFigure, 'text');
            app.ImportButton_3 = uibutton(app.UIFigure, 'push');
            app.ImportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_3Pushed, true);
            app.ImportButton_3.Text = 'Import';
            app.Audio3EditFieldLabel = uilabel(app.UIFigure);
            app.Audio3EditFieldLabel.Text = 'Audio 3';
            app.Audio3EditField = uieditfield(app.UIFigure, 'text');
            app.basepitchKnobLabel = uilabel(app.UIFigure);
            app.basepitchKnobLabel.Text = 'base pitch';
            app.basepitchKnob = uiknob(app.UIFigure, 'continuous');
            app.basepitchKnob.Limits = [-12 5];
            app.basepitchKnob.ValueChangedFcn = createCallbackFcn(app, @basepitchKnobValueChanged, true);
            app.basepitchKnob.Value = -10;
            app.interval1Label = uilabel(app.UIFigure);
            app.interval1Label.Text = 'interval 1';
            app.interval1Knob = uiknob(app.UIFigure, 'discrete');
            app.interval1Knob.Items = {'-12', '-9', '-7', '-5', '-4', '-3', '-2', '-1', '0', '1', '2', '3', '4', '5', '7', '9', '12'};
            app.interval1Knob.ValueChangedFcn = createCallbackFcn(app, @interval1KnobValueChanged, true);
            app.interval1Knob.Value = '0';
            app.interval2Label = uilabel(app.UIFigure);
            app.interval2Label.Text = 'interval 2';
            app.interval2Knob = uiknob(app.UIFigure, 'discrete');
            app.interval2Knob.Items = {'-12', '-9', '-7', '-5', '-4', '-3', '-2', '-1', '0', '1', '2', '3', '4', '5', '7', '9', '12'};
            app.interval2Knob.ValueChangedFcn = createCallbackFcn(app, @interval2KnobValueChanged, true);
            app.interval2Knob.Value = '0';
            app.interval3Label = uilabel(app.UIFigure);
            app.interval3Label.Text = 'interval 3';
            app.interval3Knob = uiknob(app.UIFigure, 'discrete');
            app.interval3Knob.Items = {'-12', '-9', '-7', '-5', '-4', '-3', '-2', '-1', '0', '1', '2', '3', '4', '5', '7', '9', '12'};
            app.interval3Knob.ValueChangedFcn = createCallbackFcn(app, @interval3KnobValueChanged, true);
            app.interval3Knob.Value = '0';
            app.detune1Label = uilabel(app.UIFigure);
            app.detune1Label.Text = 'detune 1';
            app.detune1Knob = uiknob(app.UIFigure, 'discrete');
            app.detune1Knob.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.detune1Knob.ValueChangedFcn = createCallbackFcn(app, @detune1KnobValueChanged, true);
            app.detune1Knob.Value = '0';
            app.detune2Label = uilabel(app.UIFigure);
            app.detune2Label.Text = 'detune 2';
            app.detune2Knob = uiknob(app.UIFigure, 'discrete');
            app.detune2Knob.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.detune2Knob.ValueChangedFcn = createCallbackFcn(app, @detune2KnobValueChanged, true);
            app.detune2Knob.Value = '0';
            app.detune3Label = uilabel(app.UIFigure);
            app.detune3Label.Text = 'detune 3';
            app.detune3Knob = uiknob(app.UIFigure, 'discrete');
            app.detune3Knob.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.detune3Knob.ValueChangedFcn = createCallbackFcn(app, @detune3KnobValueChanged, true);
            app.detune3Knob.Value = '0';
            app.pitchrangeKnobLabel = uilabel(app.UIFigure);
            app.pitchrangeKnobLabel.Text = 'pitch range';
            app.pitchrangeKnob = uiknob(app.UIFigure, 'discrete');
            app.pitchrangeKnob.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.pitchrangeKnob.ValueChangedFcn = createCallbackFcn(app, @pitchrangeKnobValueChanged, true);
            app.pitchrangeKnob.Value = '0';
            app.MasterVolumeKnobLabel = uilabel(app.UIFigure);
            app.MasterVolumeKnobLabel.Text = {'Master'; 'Volume'};
            app.MasterVolumeKnob = uiknob(app.UIFigure, 'continuous');
            app.MasterVolumeKnob.Limits = [0 1];
            app.MasterVolumeKnob.ValueChangedFcn = createCallbackFcn(app, @MasterVolumeKnobValueChanged, true);
            app.MasterVolumeKnob.Value = 1;
            app.filter1typeDropDownLabel = uilabel(app.UIFigure);
            app.filter1typeDropDownLabel.Text = 'filter 1 type';
            app.filter1typeDropDown = uidropdown(app.UIFigure);
            app.filter1typeDropDown.Items = {'Lowpass', 'Bandpass', 'Highpass'};
            app.filter1typeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @filter1typeDropDownOpening, true);
            app.filter1typeDropDown.ValueChangedFcn = createCallbackFcn(app, @filter1typeDropDownValueChanged, true);
            app.filter1typeDropDown.Value = 'Lowpass';
            app.Filter1cutoffEditFieldLabel = uilabel(app.UIFigure);
            app.Filter1cutoffEditFieldLabel.Text = 'Filter 1 cutoff';
            app.Filter1cutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.Filter1cutoffEditField.ValueChangedFcn = createCallbackFcn(app, @Filter1cutoffEditFieldValueChanged, true);
            app.filter2typeDropDownLabel = uilabel(app.UIFigure);
            app.filter2typeDropDownLabel.Text = 'filter 2 type';
            app.filter2typeDropDown = uidropdown(app.UIFigure);
            app.filter2typeDropDown.Items = {'Lowpass', 'Bandpass', 'Highpass'};
            app.filter2typeDropDown.ValueChangedFcn = createCallbackFcn(app, @filter2typeDropDownValueChanged, true);
            app.filter2typeDropDown.Value = 'Lowpass';
            app.Filter2cutoffEditFieldLabel = uilabel(app.UIFigure);
            app.Filter2cutoffEditFieldLabel.Text = 'Filter 2 cutoff';
            app.Filter2cutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.Filter2cutoffEditField.ValueChangedFcn = createCallbackFcn(app, @Filter2cutoffEditFieldValueChanged, true);
            app.filter3typeDropDownLabel = uilabel(app.UIFigure);
            app.filter3typeDropDownLabel.Text = 'filter 3 type';
            app.filter3typeDropDown = uidropdown(app.UIFigure);
            app.filter3typeDropDown.Items = {'Lowpass', 'Bandpass', 'Highpass'};
            app.filter3typeDropDown.ValueChangedFcn = createCallbackFcn(app, @filter3typeDropDownValueChanged, true);
            app.filter3typeDropDown.Value = 'Lowpass';
            app.Filter3cutoffEditFieldLabel = uilabel(app.UIFigure);
            app.Filter3cutoffEditFieldLabel.Text = 'Filter 3 cutoff';
            app.Filter3cutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.Filter3cutoffEditField.ValueChangedFcn = createCallbackFcn(app, @Filter3cutoffEditFieldValueChanged, true);
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.bandwidthEditFieldLabel = uilabel(app.UIFigure);
            app.bandwidthEditFieldLabel.Text = 'bandwidth';
            app.bandwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.bandwidthEditField.ValueChangedFcn = createCallbackFcn(app, @bandwidthEditFieldValueChanged, true);
            app.bandwidthEditField_2Label = uilabel(app.UIFigure);
            app.bandwidthEditField_2Label.Text = 'bandwidth';
            app.bandwidthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.bandwidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @bandwidthEditField_2ValueChanged, true);
            app.bandwidthEditField_3Label = uilabel(app.UIFigure);
            app.bandwidthEditField_3Label.Text = 'bandwidth';
            app.bandwidthEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.bandwidthEditField_3.ValueChangedFcn = createCallbackFcn(app, @bandwidthEditField_3ValueChanged, true);
            app.ADVANCEDOPTIONSButton = uibutton(app.UIFigure, 'push');
            app.ADVANCEDOPTIONSButton.ButtonPushedFcn = createCallbackFcn(app, @ADVANCEDOPTIONSButtonPushed, true);
            app.ADVANCEDOPTIONSButton.Text = {'ADVANCED'; 'OPTIONS'};
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.SetAsDefaultAudioFilesButton = uibutton(app.UIFigure, 'push');
            app.SetAsDefaultAudioFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SetAsDefaultAudioFilesButtonPushed, true);
            app.SetAsDefaultAudioFilesButton.Text = {'Set As Default'; 'Audio Files'};
            app.loadpresetButton = uibutton(app.UIFigure, 'push');
            app.loadpresetButton.ButtonPushedFcn = createCallbackFcn(app, @loadpresetButtonPushed, true);
            app.loadpresetButton.Text = 'load preset';
            app.savepresetButton = uibutton(app.UIFigure, 'push');
            app.savepresetButton.ButtonPushedFcn = createCallbackFcn(app, @savepresetButtonPushed, true);
            app.savepresetButton.Text = 'save preset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.AGSynthLabel = uilabel(app.UIFigure);
            app.AGSynthLabel.Text = 'AGSynth';
            app.SIMULINKOPTIONSLabel = uilabel(app.UIFigure);
            app.SIMULINKOPTIONSLabel.Text = 'SIMULINK OPTIONS';
            app.PRESETSLabel = uilabel(app.UIFigure);
            app.PRESETSLabel.Text = 'PRESETS';
            app.ResampleButton = uibutton(app.UIFigure, 'push');
            app.ResampleButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleButtonPushed, true);
            app.ResampleButton.Text = 'Resample';
            app.SamplingfreqHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingfreqHzEditFieldLabel.Text = 'Sampling freq (Hz)';
            app.SamplingfreqHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingfreqHzEditField.Value = 44100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end