classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LinkknobsButton               matlab.ui.control.Button
        CosFactor3Knob                matlab.ui.control.Knob
        CosFactor3KnobLabel           matlab.ui.control.Label
        CosFactor1Knob                matlab.ui.control.Knob
        CosFactor1KnobLabel           matlab.ui.control.Label
        CosFactor2Knob                matlab.ui.control.Knob
        Knob_2Label_3                 matlab.ui.control.Label
        VariancemsKnob                matlab.ui.control.Knob
        VariancemsKnobLabel           matlab.ui.control.Label
        MaxGrainSizemsKnob            matlab.ui.control.Knob
        Knob_2Label_2                 matlab.ui.control.Label
        PitchDynamicsDropDown_3       matlab.ui.control.DropDown
        PitchDynamicsDropDown_3Label  matlab.ui.control.Label
        PitchDynamicsDropDown_2       matlab.ui.control.DropDown
        PitchDynamicsDropDown_2Label  matlab.ui.control.Label
        PitchDynamicsDropDown         matlab.ui.control.DropDown
        PitchDynamicsDropDownLabel    matlab.ui.control.Label
        MinGrainSizemsKnob            matlab.ui.control.Knob
        MinGrainSizemsKnobLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        mdl = 'merge_variable_grain'; % Description
        link_knobs = 0;
        callingapp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.callingapp = mainapp;
            s = mainapp.state;
            app.MinGrainSizemsKnob.Value = str2double(s.mingrainsize);
            app.MaxGrainSizemsKnob.Value = str2double(s.maxgrainsize);
            app.VariancemsKnob.Value = str2double(s.max_variance);
            app.PitchDynamicsDropDown.Value = s.pitch_dyn_1;
            app.PitchDynamicsDropDown_2.Value = s.pitch_dyn_2;
            app.PitchDynamicsDropDown_3.Value = s.pitch_dyn_3;
            app.CosFactor1Knob.Value = str2double(s.cosfraction1);
            app.CosFactor2Knob.Value = str2double(s.cosfraction2);
            app.CosFactor3Knob.Value = str2double(s.cosfraction3);
        end

        % Value changed function: MinGrainSizemsKnob
        function MinGrainSizemsKnobValueChanged(app, event)
            value = app.MinGrainSizemsKnob.Value;
            if value > app.MaxGrainSizemsKnob.Value
                app.MinGrainSizemsKnob.Value = str2double(get_param([app.mdl, '/min_grain_size' ], 'Value'));        
                get_param([app.mdl, '/max_grain_size' ], 'Value');          
                return;
            end
            set_param([app.mdl, '/min_grain_size' ], 'Value', string(value))
            app.callingapp.state.mingrainsize = string(value);
        end

        % Value changed function: MaxGrainSizemsKnob
        function MaxGrainSizemsKnobValueChanged(app, event)
            value = app.MaxGrainSizemsKnob.Value;
            if value < app.MinGrainSizemsKnob.Value
                disp("ahia")
                app.MaxGrainSizemsKnob.Value = str2double(get_param([app.mdl, '/max_grain_size' ], 'Value')); 
                return;
            end
            set_param([app.mdl, '/max_grain_size' ], 'Value', string(value))
            app.callingapp.state.maxgrainsize = string(value);
        end

        % Value changed function: VariancemsKnob
        function VariancemsKnobValueChanged(app, event)
            value = app.VariancemsKnob.Value;
            set_param([app.mdl, '/variation on average length' ], 'Value', string(value))
            app.callingapp.state.max_variance = string(value);
        end

        % Value changed function: PitchDynamicsDropDown
        function PitchDynamicsDropDownValueChanged(app, event)
            value = app.PitchDynamicsDropDown.Value;
            set_param([app.mdl, '/voice_settings/exp1' ], 'Value', value)
            app.callingapp.state.pitch_dyn_1 = string(value);
        end

        % Value changed function: PitchDynamicsDropDown_2
        function PitchDynamicsDropDown_2ValueChanged(app, event)
            value = app.PitchDynamicsDropDown_2.Value;
            set_param([app.mdl, '/voice_settings/exp2' ], 'Value', value)
            app.callingapp.state.pitch_dyn_2 = string(value);
        end

        % Value changed function: PitchDynamicsDropDown_3
        function PitchDynamicsDropDown_3ValueChanged(app, event)
            value = app.PitchDynamicsDropDown_3.Value;
            set_param([app.mdl, '/voice_settings/exp3' ], 'Value', value)
            app.callingapp.state.pitch_dyn_3 = string(value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.callingapp)
                app.callingapp.ADVANCEDOPTIONSButton.Enable = "on";
            end
            
            delete(app)
            
        end

        % Value changed function: CosFactor1Knob
        function CosFactor1KnobValueChanged(app, event)
            value = app.CosFactor1Knob.Value;
            set_param([app.mdl, '/Cosine Fraction1' ], 'Value', string(value))
            if app.link_knobs == 1
                set_param([app.mdl, '/Cosine Fraction2' ], 'Value', string(value))
                app.CosFactor2Knob.Value = value;
                set_param([app.mdl, '/Cosine Fraction3' ], 'Value', string(value))
                app.CosFactor3Knob.Value = value;
            end
            app.callingapp.state.cosfraction1 = string(value);
        end

        % Value changed function: CosFactor2Knob
        function CosFactor2KnobValueChanged(app, event)
            value = app.CosFactor2Knob.Value;
            set_param([app.mdl, '/Cosine Fraction2' ], 'Value', string(value))
            app.callingapp.state.cosfraction2 = string(value);
        end

        % Value changed function: CosFactor3Knob
        function CosFactor3KnobValueChanged(app, event)
            value = app.CosFactor3Knob.Value;
            set_param([app.mdl, '/Cosine Fraction3' ], 'Value', string(value))
            app.callingapp.state.cosfraction3 = string(value);
        end

        % Button pushed function: LinkknobsButton
        function LinkknobsButtonPushed(app, event)
            app.link_knobs = mod((app.link_knobs + 1), 2);
            if app.link_knobs == 1
                app.LinkknobsButton.Text = "Unlink knobs";
                app.CosFactor2Knob.Enable = 0;
                app.CosFactor3Knob.Enable = 0;
            elseif app.link_knobs == 0
                app.LinkknobsButton.Text = "Link knobs";
                app.CosFactor2Knob.Enable = 1;
                app.CosFactor3Knob.Enable = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MinGrainSizemsKnobLabel = uilabel(app.UIFigure);
            app.MinGrainSizemsKnobLabel.Text = 'Min Grain Size (ms)';
            app.MinGrainSizemsKnob = uiknob(app.UIFigure, 'continuous');
            app.MinGrainSizemsKnob.Limits = [100 1000];
            app.MinGrainSizemsKnob.ValueChangedFcn = createCallbackFcn(app, @MinGrainSizemsKnobValueChanged, true);
            app.MinGrainSizemsKnob.Value = 100;
            app.PitchDynamicsDropDownLabel = uilabel(app.UIFigure);
            app.PitchDynamicsDropDownLabel.Text = 'Pitch Dynamics';
            app.PitchDynamicsDropDown = uidropdown(app.UIFigure);
            app.PitchDynamicsDropDown.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.PitchDynamicsDropDown.ItemsData = {'1', '2', '3'};
            app.PitchDynamicsDropDown.ValueChangedFcn = createCallbackFcn(app, @PitchDynamicsDropDownValueChanged, true);
            app.PitchDynamicsDropDown.Value = '1';
            app.PitchDynamicsDropDown_2Label = uilabel(app.UIFigure);
            app.PitchDynamicsDropDown_2Label.Text = 'Pitch Dynamics';
            app.PitchDynamicsDropDown_2 = uidropdown(app.UIFigure);
            app.PitchDynamicsDropDown_2.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.PitchDynamicsDropDown_2.ItemsData = {'1', '2', '3'};
            app.PitchDynamicsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PitchDynamicsDropDown_2ValueChanged, true);
            app.PitchDynamicsDropDown_2.Value = '1';
            app.PitchDynamicsDropDown_3Label = uilabel(app.UIFigure);
            app.PitchDynamicsDropDown_3Label.Text = 'Pitch Dynamics';
            app.PitchDynamicsDropDown_3 = uidropdown(app.UIFigure);
            app.PitchDynamicsDropDown_3.Items = {'Linear', 'Quadratic', 'Cubic'};
            app.PitchDynamicsDropDown_3.ItemsData = {'1', '2', '3'};
            app.PitchDynamicsDropDown_3.ValueChangedFcn = createCallbackFcn(app, @PitchDynamicsDropDown_3ValueChanged, true);
            app.PitchDynamicsDropDown_3.Value = '1';
            app.Knob_2Label_2 = uilabel(app.UIFigure);
            app.Knob_2Label_2.Text = 'Max Grain Size (ms)';
            app.MaxGrainSizemsKnob = uiknob(app.UIFigure, 'continuous');
            app.MaxGrainSizemsKnob.Limits = [300 1000];
            app.MaxGrainSizemsKnob.ValueChangedFcn = createCallbackFcn(app, @MaxGrainSizemsKnobValueChanged, true);
            app.MaxGrainSizemsKnob.Value = 800;
            app.VariancemsKnobLabel = uilabel(app.UIFigure);
            app.VariancemsKnobLabel.Text = 'Variance [ms]';
            app.VariancemsKnob = uiknob(app.UIFigure, 'continuous');
            app.VariancemsKnob.Limits = [30 200];
            app.VariancemsKnob.ValueChangedFcn = createCallbackFcn(app, @VariancemsKnobValueChanged, true);
            app.VariancemsKnob.Value = 200;
            app.Knob_2Label_3 = uilabel(app.UIFigure);
            app.Knob_2Label_3.Text = 'Cos Factor 2';
            app.CosFactor2Knob = uiknob(app.UIFigure, 'continuous');
            app.CosFactor2Knob.Limits = [0 1];
            app.CosFactor2Knob.ValueChangedFcn = createCallbackFcn(app, @CosFactor2KnobValueChanged, true);
            app.CosFactor2Knob.Value = 1;
            app.CosFactor1KnobLabel = uilabel(app.UIFigure);
            app.CosFactor1KnobLabel.Text = 'Cos Factor 1';
            app.CosFactor1Knob = uiknob(app.UIFigure, 'continuous');
            app.CosFactor1Knob.Limits = [0 1];
            app.CosFactor1Knob.ValueChangedFcn = createCallbackFcn(app, @CosFactor1KnobValueChanged, true);
            app.CosFactor1Knob.Value = 1;
            app.CosFactor3KnobLabel = uilabel(app.UIFigure);
            app.CosFactor3KnobLabel.Text = 'Cos Factor 3';
            app.CosFactor3Knob = uiknob(app.UIFigure, 'continuous');
            app.CosFactor3Knob.Limits = [0 1];
            app.CosFactor3Knob.ValueChangedFcn = createCallbackFcn(app, @CosFactor3KnobValueChanged, true);
            app.CosFactor3Knob.Value = 1;
            app.LinkknobsButton = uibutton(app.UIFigure, 'push');
            app.LinkknobsButton.ButtonPushedFcn = createCallbackFcn(app, @LinkknobsButtonPushed, true);
            app.LinkknobsButton.Text = 'Link knobs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end