classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Switch                   matlab.ui.control.ToggleSwitch
        Label                    matlab.ui.control.Label
        BRAKESlider              matlab.ui.control.Slider
        BRAKESliderLabel         matlab.ui.control.Label
        THROTTLESlider           matlab.ui.control.Slider
        THROTTLESliderLabel      matlab.ui.control.Label
        SPEEDSlider              matlab.ui.control.Slider
        SPEEDSliderLabel         matlab.ui.control.Label
        RPMEditField             matlab.ui.control.NumericEditField
        SPEEDEditField_2Label_2  matlab.ui.control.Label
        SPEEDmphEditField        matlab.ui.control.NumericEditField
        SPEEDmphEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        mdl = 'merge_variable_grain'; % Description
    end
    
    methods (Access = public)
        
        function updateGUI(app)
             % Create an object that gets the run-time value of the specified block
             rto = get_param([app.mdl,'/Biquad Filtering/speed_disp'],'RuntimeObject');         
             app.SPEEDmphEditField.Value = rto.InputPort(1).Data;

             rto = get_param([app.mdl,'/Biquad Filtering/rpm_disp'],'RuntimeObject');         
             app.RPMEditField.Value = rto.InputPort(1).Data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.SPEEDSlider.Value;
            set_param([app.mdl, '/Biquad Filtering/Dashboard ECU logic1/Commanded speed/Speed command' ], ...
                'Value', string(value))
            value = app.THROTTLESlider.Value;
            set_param([app.mdl, '/Speed controller/Speed controller ECU logic/t_c' ], 'Value', string(value))
            value = app.BRAKESlider.Value;
            set_param([app.mdl, '/Speed controller/Speed controller ECU logic/b_c' ], 'Value', string(value))
            value = app.Switch.Value;
            if value == '0' %manual control
                set_param([app.mdl, '/Speed controller/Speed controller ECU logic/control type switch' ], 'sw', '0')
                app.SPEEDSlider.Enable = "off";
                app.BRAKESlider.Enable = "on";
                app.THROTTLESlider.Enable = "on";
            elseif value == '1' %cruise
                set_param([app.mdl, '/Speed controller/Speed controller ECU logic/control type switch' ], 'sw', '1')
                app.SPEEDSlider.Enable = "on";
                app.BRAKESlider.Enable = "off";
                app.THROTTLESlider.Enable = "off";
            end
        end

        % Value changed function: SPEEDSlider
        function SPEEDSliderValueChanged(app, event)
            value = app.SPEEDSlider.Value;
            set_param([app.mdl, '/Biquad Filtering/Dashboard ECU logic1/Commanded speed/Speed command' ], ...
                'Value', string(value))
        end

        % Value changed function: THROTTLESlider
        function THROTTLESliderValueChanged(app, event)
            value = app.THROTTLESlider.Value;
            set_param([app.mdl, '/Speed controller/Speed controller ECU logic/t_c' ], 'Value', string(value))
        end

        % Value changed function: BRAKESlider
        function BRAKESliderValueChanged(app, event)
            value = app.BRAKESlider.Value;
            set_param([app.mdl, '/Speed controller/Speed controller ECU logic/b_c' ], 'Value', string(value))
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == '0' %manual control
                set_param([app.mdl, '/Speed controller/Speed controller ECU logic/control type switch' ], 'sw', '0')
                app.SPEEDSlider.Enable = "off";
                app.BRAKESlider.Enable = "on";
                app.THROTTLESlider.Enable = "on";
            elseif value == '1' %cruise               
                set_param([app.mdl, '/Speed controller/Speed controller ECU logic/control type switch' ], 'sw', '1')
                app.SPEEDSlider.Enable = "on";
                app.BRAKESlider.Enable = "off";
                app.THROTTLESlider.Enable = "off";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            simulink.compiler.stopSimulation(app.mdl);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SPEEDmphEditFieldLabel = uilabel(app.UIFigure);
            app.SPEEDmphEditFieldLabel.Text = 'SPEED mph';
            app.SPEEDmphEditField = uieditfield(app.UIFigure, 'numeric');
            app.SPEEDEditField_2Label_2 = uilabel(app.UIFigure);
            app.SPEEDEditField_2Label_2.Text = 'RPM';
            app.RPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.SPEEDSliderLabel = uilabel(app.UIFigure);
            app.SPEEDSliderLabel.Text = 'SPEED';
            app.SPEEDSlider = uislider(app.UIFigure);
            app.SPEEDSlider.Limits = [0 200];
            app.SPEEDSlider.ValueChangedFcn = createCallbackFcn(app, @SPEEDSliderValueChanged, true);
            app.THROTTLESliderLabel = uilabel(app.UIFigure);
            app.THROTTLESliderLabel.Text = 'THROTTLE';
            app.THROTTLESlider = uislider(app.UIFigure);
            app.THROTTLESlider.Limits = [0 1];
            app.THROTTLESlider.ValueChangedFcn = createCallbackFcn(app, @THROTTLESliderValueChanged, true);
            app.BRAKESliderLabel = uilabel(app.UIFigure);
            app.BRAKESliderLabel.Text = 'BRAKE';
            app.BRAKESlider = uislider(app.UIFigure);
            app.BRAKESlider.Limits = [0 1];
            app.BRAKESlider.ValueChangedFcn = createCallbackFcn(app, @BRAKESliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'Cruise ctrl', 'Manual ctrl'};
            app.Switch.ItemsData = {'1', '0'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end